classdef UnifiedApp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        SaveLoadPanel                  matlab.ui.container.Panel
        LoadButton                     matlab.ui.control.Button
        SaveButton                     matlab.ui.control.Button
        TerrainoptionsPanel            matlab.ui.container.Panel
        ChangeterrianpointheightKnob   matlab.ui.control.Knob
        ChangeterrianpointheightLabel  matlab.ui.control.Label
        GaurdheightEditField           matlab.ui.control.NumericEditField
        GaurdheightEditFieldLabel      matlab.ui.control.Label
        NumgaurdsSpinner               matlab.ui.control.Spinner
        NumgaurdsSpinnerLabel          matlab.ui.control.Label
        TerrainseedEditField           matlab.ui.control.NumericEditField
        TerrainseedEditFieldLabel      matlab.ui.control.Label
        TerrainlengthSpinner           matlab.ui.control.Spinner
        TerrainlengthSpinnerLabel      matlab.ui.control.Label
        UniformgaurdsButton            matlab.ui.control.Button
        IsparallelSwitch               matlab.ui.control.Switch
        IsparallelSwitchLabel          matlab.ui.control.Label
        Problem3Button                 matlab.ui.control.Button
        Problem2Button                 matlab.ui.control.Button
        Problem1Button                 matlab.ui.control.Button
        GaurdoptionsPanel              matlab.ui.container.Panel
        StatusLabel                    matlab.ui.control.Label
        StatusStaticLabel              matlab.ui.control.Label
        ActiveSwitch                   matlab.ui.control.Switch
        Button_2                       matlab.ui.control.Button
        Button                         matlab.ui.control.Button
        LocationSlider                 matlab.ui.control.Slider
        LocationSliderLabel            matlab.ui.control.Label
        UIAxes                         matlab.ui.control.UIAxes
    end

    
    % Author: Amir Mohammad Esmaieeli Sikaroudi
    % GUI for visibility of gaurds
    % Known issues: Last gaurd never can become active.
    
    properties (Access = private)
    end
    
    properties (Access = public)
        terrainSize=100; % Description
        seed=123;
        terrainPoints % Description
        faces
        vertices
        X
        Y
        numCandidateGaurds=20;
        candidateGaurds=[];
        height=80;
        activeGaurds=[];
        selectedGaurd=1;
        selectedTerrainPoint=-1;
        preprocessRequired=[];
        preprocessedVisibility=[];
        isParallel=1;
        angleOfView;
    end

    methods (Access = public)

        function initTerrain(app)
            [app.terrainPoints,app.faces,app.vertices,app.X,app.Y] = generate2DStepTerrain(app,app.terrainSize);
            app.angleOfView=[0,0];
            view(app.UIAxes,app.angleOfView)
        end
        
        function preprocessVisibility(app)
            app.StatusLabel.Text='Preprocessing';
            drawnow
            if size(app.preprocessedVisibility,2)>size(app.terrainPoints,2)*2
                for i=size(app.preprocessedVisibility,2):-1:(size(app.terrainPoints,2)*2)-1
                    app.preprocessedVisibility(:,i)=[];
                end
            end
            %app.preprocessedVisibility=[];
            %app.preprocessedVisibility(app.numCandidateGaurds,1)=0;
            for i=1:app.numCandidateGaurds
                if app.preprocessRequired(1,i)==1
                    orig=[app.candidateGaurds(1,i),1,app.height];
                    [~,visibleVerticesSizes] = calcVisibility(orig,app.vertices,app.faces,app.X,app.Y,app.terrainPoints,app.isParallel);
                    for h=1:size(visibleVerticesSizes,1)
                        if visibleVerticesSizes(h,1)>1
                            app.preprocessedVisibility(i,h)=1;
                        else
                            app.preprocessedVisibility(i,h)=0;
                        end
                    end
                    app.preprocessRequired(1,i)=0;
                end
            end
            app.StatusLabel.Text='Idle';
        end
        
        function showGaurds(app)
            app.activeGaurds(1,app.numCandidateGaurds)=0;
            numActiveGaurds=sum(app.activeGaurds);
            rawColors=hsv(numActiveGaurds);
            %colors = rawColors(randperm(size(rawColors, 1)),:);
            colors=rawColors;
            counter=1;
            for i=1:size(app.candidateGaurds,2)
                if app.selectedGaurd==i
                    if app.activeGaurds(1,i)==0
                        s=scatter3(app.UIAxes,app.candidateGaurds(1,i),1,app.height,'MarkerEdgeColor',[0,0,0],'MarkerFaceColor',[0.5,0.5,0.5]);
                        s.UserData='Gaurds';
                    else
                        s=scatter3(app.UIAxes,app.candidateGaurds(1,i),1,app.height,'MarkerEdgeColor',[0,0,0],'MarkerFaceColor',colors(counter,:));
                        s.UserData='Gaurds';
                        %drawVisibilityOfGaurd(app,i,colors(counter,:));
                        counter=counter+1;
                    end
                else
                    if app.activeGaurds(1,i)==0
                        s=scatter3(app.UIAxes,app.candidateGaurds(1,i),1,app.height,'MarkerEdgeColor',[1,1,1],'MarkerFaceColor',[0.5,0.5,0.5]);
                        s.UserData='Gaurds';
                    else
                        s=scatter3(app.UIAxes,app.candidateGaurds(1,i),1,app.height,'MarkerEdgeColor',[1,1,1],'MarkerFaceColor',colors(counter,:));
                        s.UserData='Gaurds';
                        %drawVisibilityOfGaurd(app,i,colors(counter,:));
                        counter=counter+1;
                    end
                end
            end
        end
        
        function showSelectedTerrainPoint(app)
            for i=1:size(app.terrainPoints,2)
                if app.selectedTerrainPoint==i
                    s=scatter3(app.UIAxes,app.X(1,i),app.Y(1,i),app.terrainPoints(1,i),'MarkerEdgeColor',[0,0,0],'MarkerFaceColor',[0.9,0.0,0.0]);
                    s.UserData='TerrainPoint';
                end
            end
        end
        
        function drawVisibilityOfGaurds(app)
            app.activeGaurds(1,app.numCandidateGaurds)=0;
            numActiveGaurds=sum(app.activeGaurds);
            rawColors=hsv(numActiveGaurds);
            %colors = rawColors(randperm(size(rawColors, 1)),:);
            colors=rawColors;
            counter=1;
            for i=1:size(app.candidateGaurds,2)
                if app.activeGaurds(1,i)==1
                    generatedX=[];
                    generatedY=[];
                    generatedZ=[];
                    for h=1:size(app.preprocessedVisibility,2)
                        if app.preprocessedVisibility(i,h)==1
                            x_h=app.vertices(h,2);
                            y_h=app.vertices(h,1);
                            generatedX(1,size(generatedX,2)+1)=app.X(x_h,y_h);
                            generatedY(1,size(generatedY,2)+1)=app.Y(x_h,y_h);
                            generatedZ(1,size(generatedZ,2)+1)=app.terrainPoints(x_h,y_h);
                        end
                    end
                    s=scatter3(app.UIAxes,generatedX,generatedY,generatedZ+i/2,'MarkerEdgeColor',colors(counter,:),'MarkerFaceColor',colors(counter,:));
                    s.UserData='Visibility';
                    counter=counter+1;
                end
            end
        end
        
        function distGaurdsUniform(app)
            app.candidateGaurds = linspace(1,app.terrainSize,app.numCandidateGaurds);
        end
        
        function updateTerrain(app)
            [a1,a2]=view(app.UIAxes);
            app.angleOfView=[a1,a2];
            for i=1:size(app.UIAxes.Children,1)
                if strcmp(app.UIAxes.Children(i,1).UserData,'Terrain')
                    delete(app.UIAxes.Children(i,1));
                    break;
                end
            end
            s=surf(app.UIAxes,app.X,app.Y,app.terrainPoints,'FaceColor','w');
            s.UserData='Terrain';
            daspect(app.UIAxes,[1,1,1])
            view(app.UIAxes,app.angleOfView);
            app.UIAxes.ButtonDownFcn = createCallbackFcn(app, @UIAxesButtonDown, true);
        end
        
        function updateGaurds(app)
            for i=size(app.UIAxes.Children,1):-1:1
                if strcmp(app.UIAxes.Children(i,1).UserData,'Gaurds')
                    delete(app.UIAxes.Children(i,1));
                end
            end
            showGaurds(app);
            app.UIAxes.ButtonDownFcn = createCallbackFcn(app, @UIAxesButtonDown, true);
        end
        
        function updateVisibility(app)
            for i=size(app.UIAxes.Children,1):-1:1
                if strcmp(app.UIAxes.Children(i,1).UserData,'Visibility')
                    delete(app.UIAxes.Children(i,1));
                end
            end
            drawVisibilityOfGaurds(app);
            app.UIAxes.ButtonDownFcn = createCallbackFcn(app, @UIAxesButtonDown, true);
        end
        
        function updateSelectedTerrain(app)
            for i=1:size(app.UIAxes.Children,1)
                if strcmp(app.UIAxes.Children(i,1).UserData,'TerrainPoint')
                    delete(app.UIAxes.Children(i,1));
                    break;
                end
            end
            showSelectedTerrainPoint(app);
            app.UIAxes.ButtonDownFcn = createCallbackFcn(app, @UIAxesButtonDown, true);
        end
        
        function fullUpdateGraph(app)
            updateTerrain(app);
            preprocessVisibility(app);
            updateGaurds(app);
            updateVisibility(app);
            updateSelectedTerrain(app);
        end
        
%         function updateGraph(app)
%             [a1,a2]=view(app.UIAxes);
%             app.angleOfView=[a1,a2];
%             cla(app.UIAxes,'reset')
%             s=surf(app.UIAxes,app.X,app.Y,app.terrainPoints,'FaceColor','w');
%             s.UserData='Terrain';
%             daspect(app.UIAxes,[1,1,1])
%             view(app.UIAxes,app.angleOfView)
%             preprocessVisibility(app);
%             showGaurds(app);
%             showSelectedTerrainPoint(app);
%             app.UIAxes.ButtonDownFcn = createCallbackFcn(app, @UIAxesButtonDown, true);
%         end
        
        function initGaurds(app)
            distGaurdsUniform(app);
            app.preprocessRequired=ones(1,app.numCandidateGaurds);
            app.preprocessedVisibility(app.numCandidateGaurds,1)=0;
        end
        
        function [terrainPoints,faces,vertices,X,Y] = generate2DStepTerrain(app,sizeTerrain)
            [X,Y] = meshgrid(1:sizeTerrain,1:2);
            faces = delaunay(X,Y);
            terrainPoints(2,sizeTerrain)=0;
            rng(app.seed)
            segmentSizeAvg=8+round(rand(1,1)*10);
            segmentSizeStd=8+round(rand(1,1)*10);
            type='dentFloor';
            isActive=false;
            offset=[];
            segmentPassed=0;
            counter=0;
            for i=1:sizeTerrain
                if isActive==false
                    %disp(type)
                    switch type
                        case 'dentFloor'
                            segmentLength=floor(segmentSizeAvg+(rand(1,1)*segmentSizeStd));
                            if isempty(offset)==1
                                offset=rand(1,1);
                            else
                                offset=terrainPoints(1,X(1,i-1));
                            end
                            slope=(rand(1,1)-0.5)*0.1;
                        case 'dentTop'
                            segmentLength=floor(segmentSizeAvg+(rand(1,1)*segmentSizeStd));
                            offset=terrainPoints(1,X(1,i-1));
                            slope=(rand(1,1)-0.5)*0.1;
                        case 'dentDown'
                            segmentLength=floor((segmentSizeAvg+(rand(1,1)*segmentSizeStd))/4);
                            offset=terrainPoints(1,X(1,i-1));
                            slope=-2-rand(1,1)*5;
                        case 'dentUp'
                            segmentLength=floor((segmentSizeAvg+(rand(1,1)*segmentSizeStd))/4);
                            offset=terrainPoints(1,X(1,i-1));
                            slope=2+rand(1,1)*5;
                        otherwise
                            %disp('other value')
                    end
                    isActive=true;
                end
                terrainPoints(1,X(1,i))=offset+slope*counter;
                terrainPoints(2,X(2,i))=offset+slope*counter;
                counter=counter+1;
                segmentPassed=segmentPassed+1;
                if segmentPassed>segmentLength
                    isActive=false;
                    segmentPassed=0;
                    counter=0;
                    switch type
                        case 'dentFloor'
                            type='dentUp';
                        case 'dentTop'
                            type='dentDown';
                        case 'dentDown'
                            type='dentFloor';
                        case 'dentUp'
                            type='dentTop';
                        otherwise
                            %disp('other value')
                    end
                end
            end
            vertices = [X(:) Y(:) terrainPoints(:)];
        end
        
        function reverseUIFillout(app)
            if app.activeGaurds(1,app.selectedGaurd)==1
                app.ActiveSwitch.Value='On';
            else
                app.ActiveSwitch.Value='Off';
            end
            if app.isParallel==1
                app.IsparallelSwitch.Value='On';
            else
                app.IsparallelSwitch.Value='Off';
            end
            app.TerrainlengthSpinner.Value=app.terrainSize;
            app.TerrainseedEditField.Value=app.seed;
            app.NumgaurdsSpinner.Value=app.numCandidateGaurds;
            app.GaurdheightEditField.Value=app.height;
            
        end
        
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            cd(fileparts(which(mfilename)))
            hold(app.UIAxes)
            initTerrain(app);
            initGaurds(app);
            fullUpdateGraph(app);
            %disp('!');
        end

        % Value changed function: TerrainlengthSpinner
        function TerrainlengthSpinnerValueChanged(app, event)
            value = app.TerrainlengthSpinner.Value;
            app.terrainSize=value;
            app.LocationSlider.Limits=[1,value];
            initTerrain(app);
            updateTerrain(app);
            for i=1:app.numCandidateGaurds
                app.preprocessRequired(1,i)=1;
            end
            preprocessVisibility(app);
            updateVisibility(app);
        end

        % Button pushed function: Button
        function ButtonPushed(app, event)
            if app.selectedGaurd>1
                app.selectedGaurd=app.selectedGaurd-1;
                updateGaurds(app);
                app.LocationSlider.Value=app.candidateGaurds(1,app.selectedGaurd);
                if app.activeGaurds(1,app.selectedGaurd)==1
                    app.ActiveSwitch.Value='On';
                else
                    app.ActiveSwitch.Value='Off';
                end
            end
        end

        % Button pushed function: Button_2
        function Button_2Pushed(app, event)
            if app.selectedGaurd<app.numCandidateGaurds
                app.selectedGaurd=app.selectedGaurd+1;
                updateGaurds(app);
                app.LocationSlider.Value=app.candidateGaurds(1,app.selectedGaurd);
                if app.activeGaurds(1,app.selectedGaurd)==1
                    app.ActiveSwitch.Value='On';
                else
                    app.ActiveSwitch.Value='Off';
                end
            end
        end

        % Value changed function: ActiveSwitch
        function ActiveSwitchValueChanged(app, event)
            valueRaw = app.ActiveSwitch.Value;
            if strcmp(valueRaw,'On')
                value=1;
            else
                value=0;
            end
            app.activeGaurds(1,app.selectedGaurd)=value;
            updateGaurds(app);
            updateVisibility(app);
        end

        % Button down function: UIAxes
        function UIAxesButtonDown(app, event)
            Pt = app.UIAxes.CurrentPoint(1,:);

            if abs(Pt(1,size(Pt,2))-app.height)<0.2*app.height
                closestPoint=[];
                closestPointDist=inf;
                for i=1:size(app.candidateGaurds,2)
                    if abs(Pt(1,1)-app.candidateGaurds(1,i))<closestPointDist
                        closestPointDist=abs(Pt(1,1)-app.candidateGaurds(1,i));
                        closestPoint=i;
                    end
                end
                app.selectedGaurd=closestPoint;
                updateGaurds(app);
                app.LocationSlider.Value=app.candidateGaurds(1,app.selectedGaurd);
                if app.activeGaurds(1,app.selectedGaurd)==1
                    app.ActiveSwitch.Value='On';
                else
                    app.ActiveSwitch.Value='Off';
                end
            else
                closestPoint=[];
                closestPointDist=inf;
                for i=1:size(app.terrainPoints,2)
                    if abs(Pt(1,1)-app.X(1,i))<closestPointDist
                        closestPointDist=abs(Pt(1,1)-app.X(1,i));
                        closestPoint=i;
                    end
                end
                app.selectedTerrainPoint=closestPoint;
                updateSelectedTerrain(app);
            end

            %Pt2 = app.UIAxes.CurrentPoint(2,:);
            %disp('!!!');
        end

        % Value changed function: LocationSlider
        function LocationSliderValueChanged(app, event)
            value = app.LocationSlider.Value;
            app.candidateGaurds(1,app.selectedGaurd)=value;
            app.preprocessRequired(1,app.selectedGaurd)=1;
            updateGaurds(app);
            preprocessVisibility(app);
            updateVisibility(app);
            %disp('!')
        end

        % Value changed function: GaurdheightEditField
        function GaurdheightEditFieldValueChanged(app, event)
            value = app.GaurdheightEditField.Value;
            app.height=value;
            for i=1:app.numCandidateGaurds
                app.preprocessRequired(1,i)=1;
            end
            updateGaurds(app);
            preprocessVisibility(app);
            updateVisibility(app);
        end

        % Value changed function: NumgaurdsSpinner
        function NumgaurdsSpinnerValueChanged(app, event)
            value = app.NumgaurdsSpinner.Value;
            app.numCandidateGaurds=value;
            distGaurdsUniform(app);
            for i=1:app.numCandidateGaurds
                app.preprocessRequired(1,i)=1;
            end
            updateGaurds(app);
            preprocessVisibility(app);
            updateVisibility(app);
        end

        % Value changed function: TerrainseedEditField
        function TerrainseedEditFieldValueChanged(app, event)
            value = app.TerrainseedEditField.Value;
            app.seed=value;
            initTerrain(app);
            updateTerrain(app);
            preprocessVisibility(app);
            updateVisibility(app);
        end

        % Button pushed function: UniformgaurdsButton
        function UniformgaurdsButtonPushed(app, event)
            distGaurdsUniform(app);
            for i=1:app.numCandidateGaurds
                app.preprocessRequired(1,i)=1;
            end
            updateGaurds(app);
            preprocessVisibility(app);
            updateVisibility(app);
        end

        % Value changed function: IsparallelSwitch
        function IsparallelSwitchValueChanged(app, event)
            value = app.IsparallelSwitch.Value;
            if strcmp(value,'On')
                app.isParallel=1;
            else
                app.isParallel=0;
            end
        end

        % Value changing function: ChangeterrianpointheightKnob
        function ChangeterrianpointheightKnobValueChanging(app, event)
            if app.selectedTerrainPoint>0
                changingValue = event.Value;
                step=app.height*0.001*changingValue;
                app.terrainPoints(1,app.selectedTerrainPoint)=app.terrainPoints(1,app.selectedTerrainPoint)+step;
                app.terrainPoints(2,app.selectedTerrainPoint)=app.terrainPoints(2,app.selectedTerrainPoint)+step;
                app.vertices = [app.X(:) app.Y(:) app.terrainPoints(:)];
                updateTerrain(app);
                for i=1:app.numCandidateGaurds
                    app.preprocessRequired(1,i)=1;
                end
                preprocessVisibility(app);
                updateVisibility(app);
                updateSelectedTerrain(app);
            end
        end

        % Value changed function: ChangeterrianpointheightKnob
        function ChangeterrianpointheightKnobValueChanged(app, event)
            %value = app.ChangeterrianpointheightKnob.Value;
            app.ChangeterrianpointheightKnob.Value=0;
        end

        % Button pushed function: SaveButton
        function SaveButtonPushed(app, event)
            filter = {'*.mat'};
            [file, path] = uiputfile(filter);
            if ~isequal(file,0) && ~isequal(path,0)
                vars=metaclass(app).PropertyList;
                allData=cell(1,2);
                counter=1;
                for i=1:size(vars,1)
                    if size(vars(i,1).Validation,1)==0
                        allData{counter,1}=vars(i,1).Name;
                        allData{counter,2}=eval(strcat('app.',vars(i,1).Name));
                        counter=counter+1;
                    end
                end
                save(strcat(path,file),"allData");
            end
            
        end

        % Button pushed function: LoadButton
        function LoadButtonPushed(app, event)
            [file,path] = uigetfile('*.mat');
            if ~isequal(file,0)
                load(strcat(path,file),"-mat","allData");
                for i=1:size(allData,1)
                    eval(cell2mat(strcat('app.',allData(i,1),'=','allData{',num2str(i),',2};')))
                end
                fullUpdateGraph(app);
                reverseUIFillout(app);
                %disp('!')
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.UIAxes.ButtonDownFcn = createCallbackFcn(app, @UIAxesButtonDown, true);
            app.GaurdoptionsPanel = uipanel(app.UIFigure);
            app.GaurdoptionsPanel.Title = 'Gaurd options';
            app.LocationSliderLabel = uilabel(app.GaurdoptionsPanel);
            app.LocationSliderLabel.Text = 'Location';
            app.LocationSlider = uislider(app.GaurdoptionsPanel);
            app.LocationSlider.Limits = [1 100];
            app.LocationSlider.ValueChangedFcn = createCallbackFcn(app, @LocationSliderValueChanged, true);
            app.LocationSlider.Value = 1;
            app.Button = uibutton(app.GaurdoptionsPanel, 'push');
            app.Button.ButtonPushedFcn = createCallbackFcn(app, @ButtonPushed, true);
            app.Button.Icon = 'LeftArrow.png';
            app.Button.Text = {''; ''};
            app.Button_2 = uibutton(app.GaurdoptionsPanel, 'push');
            app.Button_2.ButtonPushedFcn = createCallbackFcn(app, @Button_2Pushed, true);
            app.Button_2.Icon = 'RightArrow.png';
            app.Button_2.Text = {''; ''};
            app.ActiveSwitch = uiswitch(app.GaurdoptionsPanel, 'slider');
            app.ActiveSwitch.ValueChangedFcn = createCallbackFcn(app, @ActiveSwitchValueChanged, true);
            app.StatusStaticLabel = uilabel(app.GaurdoptionsPanel);
            app.StatusStaticLabel.Text = 'Status:';
            app.StatusLabel = uilabel(app.GaurdoptionsPanel);
            app.StatusLabel.Text = 'Idle';
            app.TerrainoptionsPanel = uipanel(app.UIFigure);
            app.TerrainoptionsPanel.Title = 'Terrain options';
            app.Problem1Button = uibutton(app.TerrainoptionsPanel, 'push');
            app.Problem1Button.Text = 'Problem 1';
            app.Problem2Button = uibutton(app.TerrainoptionsPanel, 'push');
            app.Problem2Button.Text = 'Problem 2';
            app.Problem3Button = uibutton(app.TerrainoptionsPanel, 'push');
            app.Problem3Button.Text = 'Problem 3';
            app.IsparallelSwitchLabel = uilabel(app.TerrainoptionsPanel);
            app.IsparallelSwitchLabel.Text = 'Is parallel';
            app.IsparallelSwitch = uiswitch(app.TerrainoptionsPanel, 'slider');
            app.IsparallelSwitch.ValueChangedFcn = createCallbackFcn(app, @IsparallelSwitchValueChanged, true);
            app.IsparallelSwitch.Value = 'On';
            app.UniformgaurdsButton = uibutton(app.TerrainoptionsPanel, 'push');
            app.UniformgaurdsButton.ButtonPushedFcn = createCallbackFcn(app, @UniformgaurdsButtonPushed, true);
            app.UniformgaurdsButton.Text = 'Uniform gaurds';
            app.TerrainlengthSpinnerLabel = uilabel(app.TerrainoptionsPanel);
            app.TerrainlengthSpinnerLabel.Text = 'Terrain length';
            app.TerrainlengthSpinner = uispinner(app.TerrainoptionsPanel);
            app.TerrainlengthSpinner.Limits = [2 Inf];
            app.TerrainlengthSpinner.ValueDisplayFormat = '%.0f';
            app.TerrainlengthSpinner.ValueChangedFcn = createCallbackFcn(app, @TerrainlengthSpinnerValueChanged, true);
            app.TerrainlengthSpinner.Value = 100;
            app.TerrainseedEditFieldLabel = uilabel(app.TerrainoptionsPanel);
            app.TerrainseedEditFieldLabel.Text = 'Terrain seed';
            app.TerrainseedEditField = uieditfield(app.TerrainoptionsPanel, 'numeric');
            app.TerrainseedEditField.Limits = [1 Inf];
            app.TerrainseedEditField.ValueDisplayFormat = '%.0f';
            app.TerrainseedEditField.ValueChangedFcn = createCallbackFcn(app, @TerrainseedEditFieldValueChanged, true);
            app.TerrainseedEditField.Value = 123;
            app.NumgaurdsSpinnerLabel = uilabel(app.TerrainoptionsPanel);
            app.NumgaurdsSpinnerLabel.Text = 'Num gaurds';
            app.NumgaurdsSpinner = uispinner(app.TerrainoptionsPanel);
            app.NumgaurdsSpinner.Limits = [1 Inf];
            app.NumgaurdsSpinner.ValueDisplayFormat = '%.0f';
            app.NumgaurdsSpinner.ValueChangedFcn = createCallbackFcn(app, @NumgaurdsSpinnerValueChanged, true);
            app.NumgaurdsSpinner.Value = 20;
            app.GaurdheightEditFieldLabel = uilabel(app.TerrainoptionsPanel);
            app.GaurdheightEditFieldLabel.Text = 'Gaurd height';
            app.GaurdheightEditField = uieditfield(app.TerrainoptionsPanel, 'numeric');
            app.GaurdheightEditField.ValueChangedFcn = createCallbackFcn(app, @GaurdheightEditFieldValueChanged, true);
            app.GaurdheightEditField.Value = 80;
            app.ChangeterrianpointheightLabel = uilabel(app.TerrainoptionsPanel);
            app.ChangeterrianpointheightLabel.Text = {'Change terrian'; 'point height'};
            app.ChangeterrianpointheightKnob = uiknob(app.TerrainoptionsPanel, 'continuous');
            app.ChangeterrianpointheightKnob.Limits = [-100 100];
            app.ChangeterrianpointheightKnob.ValueChangedFcn = createCallbackFcn(app, @ChangeterrianpointheightKnobValueChanged, true);
            app.ChangeterrianpointheightKnob.ValueChangingFcn = createCallbackFcn(app, @ChangeterrianpointheightKnobValueChanging, true);
            app.SaveLoadPanel = uipanel(app.UIFigure);
            app.SaveLoadPanel.Title = 'Save/Load';
            app.SaveButton = uibutton(app.SaveLoadPanel, 'push');
            app.SaveButton.ButtonPushedFcn = createCallbackFcn(app, @SaveButtonPushed, true);
            app.SaveButton.Text = 'Save';
            app.LoadButton = uibutton(app.SaveLoadPanel, 'push');
            app.LoadButton.ButtonPushedFcn = createCallbackFcn(app, @LoadButtonPushed, true);
            app.LoadButton.Text = 'Load';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = UnifiedApp

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end